classdef test0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image_doctor             matlab.ui.control.Image
        Image_f01                matlab.ui.control.Image
        Button_start             matlab.ui.control.Button
        Label_introduction_text  matlab.ui.control.Label
        Image_diagnosis_text     matlab.ui.control.Image
        Label_type_text          matlab.ui.control.Label
        Button_type              matlab.ui.control.Button
        Button_diagnosis         matlab.ui.control.Button
        Label_type               matlab.ui.control.Label
        Button_introduction      matlab.ui.control.Button
        Label_diagnosis          matlab.ui.control.Label
        Label_introduction       matlab.ui.control.Label
        Image_background2        matlab.ui.control.Image
        Label_systemname         matlab.ui.control.Label
        Image_background         matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Label_introduction_text.Visible = 'off';
            app.Label_introduction.Visible = 'off';
            app.Label_diagnosis.Visible = 'off';
            app.Image_diagnosis_text.Visible = 'off';
            app.Label_type.Visible = 'off';
            app.Label_type_text.Visible = 'off';
            app.Image_background2.Visible = 'off';
            app.Image_f01.Visible ='off';
            app.Image_doctor.Visible = 'off';


        end

        % Button pushed function: Button_start
        function Button_startPushed(app, event)
            test1;
        end

        % Button pushed function: Button_introduction
        function Button_introductionPushed(app, event)
            app.Image_background2.Visible = 'on';
            app.Label_systemname.Visible = 'off';
            app.Image_f01.Visible ='on';
            app.Label_introduction_text.Visible = 'on';
            app.Label_introduction.Visible = 'on';
            app.Image_doctor.Visible = 'off';
            app.Image_background.Visible = 'off';
            app.Label_diagnosis.Visible = 'off';
            app.Image_diagnosis_text.Visible = 'off';
            app.Label_type.Visible = 'off';
            app.Label_type_text.Visible = 'off';
            
            
        end

        % Button pushed function: Button_diagnosis
        function Button_diagnosisPushed(app, event)
            app.Label_systemname.Visible = 'off';
            app.Image_background2.Visible = 'on';
            app.Label_diagnosis.Visible = 'on';
            app.Image_diagnosis_text.Visible = 'on';
            app.Image_background.Visible = 'off';
            app.Label_introduction_text.Visible = 'off';
            app.Label_introduction.Visible = 'off';
            app.Label_type.Visible = 'off';
            app.Label_type_text.Visible = 'off';
            app.Image_f01.Visible ='off';
            app.Image_doctor.Visible = 'on';
        end

        % Button pushed function: Button_type
        function Button_typePushed(app, event)
            app.Label_systemname.Visible = 'off';
            app.Image_background2.Visible = 'on';
            app.Label_type_text.Visible = 'on';
            app.Label_type.Visible = 'on';
            app.Image_background.Visible = 'off';
            app.Label_introduction_text.Visible = 'off';
            app.Label_introduction.Visible = 'off';
            app.Label_diagnosis.Visible = 'off';
            app.Image_diagnosis_text.Visible = 'off';
            app.Image_f01.Visible ='off';
            app.Image_doctor.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image_background = uiimage(app.UIFigure);
            app.Image_background.ImageSource = 'hospital.jpg';
            app.Label_systemname = uilabel(app.UIFigure);
            app.Label_systemname.Text = '乳癌模糊預測系統';
            app.Image_background2 = uiimage(app.UIFigure);
            app.Image_background2.ImageSource = 'hospital1.png';
            app.Label_introduction = uilabel(app.UIFigure);
            app.Label_introduction.Text = '乳癌簡介';
            app.Label_diagnosis = uilabel(app.UIFigure);
            app.Label_diagnosis.Text = '乳癌期數診斷';
            app.Button_introduction = uibutton(app.UIFigure, 'push');
            app.Button_introduction.ButtonPushedFcn = createCallbackFcn(app, @Button_introductionPushed, true);
            app.Button_introduction.Text = '乳癌簡介';
            app.Label_type = uilabel(app.UIFigure);
            app.Label_type.Text = '乳癌類型';
            app.Button_diagnosis = uibutton(app.UIFigure, 'push');
            app.Button_diagnosis.ButtonPushedFcn = createCallbackFcn(app, @Button_diagnosisPushed, true);
            app.Button_diagnosis.Text = '乳癌期數診斷';
            app.Button_type = uibutton(app.UIFigure, 'push');
            app.Button_type.ButtonPushedFcn = createCallbackFcn(app, @Button_typePushed, true);
            app.Button_type.Text = '乳癌類型';
            app.Label_type_text = uilabel(app.UIFigure);
            app.Label_type_text.Text = {'乳癌有很多種類型，有時乳房腫瘤可能是多種乳癌結合在一起，分為侵入性乳癌及原位癌，'; '以下是常見的乳癌型態：'; ''; '(1) 乳管原位癌 (Ductal Carcinoma In Situ DCIS)：'; '      為最早期的乳癌，亦即乳癌第零期，也是最常見的一種原位癌。'; '      指腫瘤細胞仍局限在乳導管內，且癌細胞沒有經乳管壁進入乳房的脂肪組織。'; '      癌症預後極佳，而乳房攝影檢查為發現乳管原位癌最好的方式。'; ''; '(2) 乳小葉原位癌 (Lobular Carcinoma In Situ LCIS)：'; '      癌症癌細並未擴散至其他地方僅局限於乳小葉內。'; '      會增加婦女在往後罹患浸潤性乳癌的危險性。'; '      須定期做身體檢查及乳房攝影檢查。'; ''; '(3) 浸潤性乳管癌 (Infiltrating Ductal Carcinoma IDC)：'; '      最常見的乳癌，幾乎占了乳癌的 80 %。'; '      發生於乳管或乳汁通道，它們會穿過乳管壁侵入乳房之脂肪組織，並進一步擴散'; '      到身體其他的器官。'; ''; '(4) 浸潤性乳小葉癌 (Invasive Lobular Carcinoma ILC)：'; '      癌症癌細胞發生於乳小葉或乳腺，它們會擴散到身體其他器官。'; '      10 %～15 %的浸潤性乳癌屬於這種種類。'; ' '; '另外還有許多少見的乳癌類型，包括發炎性乳癌、髓狀癌、黏液癌、葉狀腫瘤及管狀癌等。'};
            app.Image_diagnosis_text = uiimage(app.UIFigure);
            app.Image_diagnosis_text.ImageSource = 'num1.jpg';
            app.Label_introduction_text = uilabel(app.UIFigure);
            app.Label_introduction_text.Text = {'乳癌的發生：'; '    乳癌是發生在乳房細胞的惡性腫瘤，多半發生在女性身上，但男性也可能會罹患乳癌。'; '大多數乳癌都發生在乳管內襯細胞（乳管癌），有些乳癌發生在乳房小葉（乳小葉癌），其'; '他乳癌則是發生在其他組織。罹患乳癌涉及到多種可能因素，其中包含家族遺傳、乳房長期'; '受內分泌激素的異常刺激、病毒傳染等其他因素。'; ''; '乳癌的症狀：'; '1.  無痛性乳房腫塊'; '2.  乳頭凹陷'; '3.  乳頭異樣分泌物，尤其是帶血分泌物'; '4.  乳房外型改變，局部凹陷或凸出變大'; '5.  乳房皮膚有橘皮樣變化，紅腫或潰爛'; '6.  乳房疼痛'; '7.  乳頭表皮有發癢、變紅、增厚、潰瘍'; '8.  腋下腫塊'; '9.  背痛'; '10. 無症狀'; ''; ''};
            app.Button_start = uibutton(app.UIFigure, 'push');
            app.Button_start.ButtonPushedFcn = createCallbackFcn(app, @Button_startPushed, true);
            app.Button_start.Text = '預測系統';
            app.Image_f01 = uiimage(app.UIFigure);
            app.Image_f01.ImageSource = 'f01.png';
            app.Image_doctor = uiimage(app.UIFigure);
            app.Image_doctor.ImageSource = 'doctor.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end