classdef test1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure              matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        Tab                       matlab.ui.container.Tab
        Image3                    matlab.ui.control.Image
        Image                     matlab.ui.control.Image
        Image2                    matlab.ui.control.Image
        Label_17                  matlab.ui.control.Label
        Label_18                  matlab.ui.control.Label
        Label_19                  matlab.ui.control.Label
        Tab_2                     matlab.ui.container.Tab
        Image4                    matlab.ui.control.Image
        Panel_3                   matlab.ui.container.Panel
        aversize                  matlab.ui.control.NumericEditField
        avershape                 matlab.ui.control.NumericEditField
        averbare                  matlab.ui.control.NumericEditField
        avernormal                matlab.ui.control.NumericEditField
        sdsize                    matlab.ui.control.NumericEditField
        sdshape                   matlab.ui.control.NumericEditField
        sdbare                    matlab.ui.control.NumericEditField
        sdnormal                  matlab.ui.control.NumericEditField
        Label_20                  matlab.ui.control.Label
        Label_21                  matlab.ui.control.Label
        Label_22                  matlab.ui.control.Label
        Label_23                  matlab.ui.control.Label
        Label_mean                matlab.ui.control.Label
        Label_standard_deviation  matlab.ui.control.Label
        step                      matlab.ui.control.Label
        Button                    matlab.ui.control.Button
        Tab_3                     matlab.ui.container.Tab
        Image5                    matlab.ui.control.Image
        Panel_4                   matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        Button_normal             matlab.ui.control.Button
        Button_shape              matlab.ui.control.Button
        Button_bare               matlab.ui.control.Button
        Button_size               matlab.ui.control.Button
        Label_low                 matlab.ui.control.Label
        Label_medium              matlab.ui.control.Label
        Label_high                matlab.ui.control.Label
        nPanel                    matlab.ui.container.Panel
        nKnobLabel                matlab.ui.control.Label
        nKnob                     matlab.ui.control.DiscreteKnob
        Button_predict            matlab.ui.control.Button
        Panel_5                   matlab.ui.container.Panel
        Label_24                  matlab.ui.control.Label
        Ssize                     matlab.ui.control.DropDown
        Label_25                  matlab.ui.control.Label
        Sshape                    matlab.ui.control.DropDown
        Label_26                  matlab.ui.control.Label
        Sbare                     matlab.ui.control.DropDown
        Label_27                  matlab.ui.control.Label
        Snormal                   matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        sizemean = 3.151;
        sizestd = 3.065;
        shapemean = 3.215;
        shapestd = 2.989;
        baremean = 3.545;
        barestd = 3.644;
        normalmean = 2.87;
        normalstd = 3.053;     
        % initial_data_863筆
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Close request function: MainUIFigure
        function MainUIFigureCloseRequest(app, event)
            
            selection = questdlg('確認要關閉視窗嗎?',...
                'Confirmation',...
                '是','否','否');
            switch selection
                case '是'
                    delete(app)
                case '否'
                    return
            end
        end

        % Button pushed function: Button_predict
        function Button_predictPushed2(app, event)
            selection = questdlg('確定要送出嗎?',...
                'Confirmation',...
                '是','否','是');
            switch selection
                case '是'
                    fis = mamfis('Name','fuzzy2');
                    
                    app.nKnob.ItemsData = [0.5 0.6 0.7 0.8 0.9];
                    c= app.nKnob.Value;
                    
                    a1 = app.sizemean;
                    b1 = app.sizestd;
                    fis = addInput(fis,[0 10],'Name','size');
                    fis = addMF(fis,'size','trimf',[-99999 a1-c*b1 a1],'Name','low');
                    fis = addMF(fis,'size','trimf',[a1-c*b1 a1 a1+c*b1],'Name','medium');
                    fis = addMF(fis,'size','trimf',[a1 a1+c*b1 99999],'Name','high');
                    
                    a2 = app.shapemean;
                    b2 = app.shapestd;
                    fis = addInput(fis,[0 10],'Name','shape');
                    fis = addMF(fis,'shape','trimf',[-99999 a2-c*b2 a2],'Name','low');
                    fis = addMF(fis,'shape','trimf',[a2-c*b2 a2 a2+c*b2],'Name','medium');
                    fis = addMF(fis,'shape','trimf',[a2 a2+c*b2 99999],'Name','high');
                    
                    a3 = app.baremean;
                    b3 = app.barestd;
                    fis = addInput(fis,[0 10],'Name','bare');
                    fis = addMF(fis,'bare','trimf',[-99999 a3-c*b3 a3],'Name','low');
                    fis = addMF(fis,'bare','trimf',[a3-c*b3 a3 a3+c*b3],'Name','medium');
                    fis = addMF(fis,'bare','trimf',[a3 a3+c*b3 99999],'Name','high');
                    
                    a4 = app.normalmean;
                    b4 = app.normalstd;
                    fis = addInput(fis,[0 10],'Name','normal');
                    fis = addMF(fis,'normal','trimf',[-99999 a4-c*b4 a4],'Name','low');
                    fis = addMF(fis,'normal','trimf',[a4-c*b4 a4 a4+c*b4],'Name','medium');
                    fis = addMF(fis,'normal','trimf',[a4 a4+c*b4 99999],'Name','high');
                    
                    fis = addOutput(fis,[0 1],'Name',"tip");
                    fis = addMF(fis,"tip","trimf",[0 0 0.25],'Name',"very_low");
                    fis = addMF(fis,"tip","trimf",[0 0.25 0.5],'Name',"low");
                    fis = addMF(fis,"tip","trimf",[0.25 0.5 0.75],'Name',"medium");
                    fis = addMF(fis,"tip","trimf",[0.5 0.75 1],'Name',"high");
                    fis = addMF(fis,"tip","trimf",[0.75 1 1],'Name',"very_high");
                    
                    
                    ruleList = [
                        3 3 3 3 5 1 1;
                        2 3 3 3 5 1 1;
                        3 2 3 3 5 1 1;
                        3 3 2 3 5 1 1;
                        3 3 3 2 5 1 1;
                        1 3 3 3 5 1 1;
                        3 1 3 3 5 1 1;
                        3 3 3 1 5 1 1;
                        3 3 1 3 5 1 1;
                        2 2 3 3 4 1 1;
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                        1 1 1 1 1 1 1
                        ];
                    fis = addRule(fis,ruleList);


                    t=test2;
                    
                    app.Ssize.ItemsData = [1 2 3 4 5 6 7 8 9 10];
                    value1 = app.Ssize.Value;
                    t.Data1=value1;
                    
                    app.Sshape.ItemsData = [1 2 3 4 5 6 7 8 9 10];
                    value2 = app.Sshape.Value;
                    t.Data2=value2;
                    
                    app.Sbare.ItemsData = [1 2 3 4 5 6 7 8 9 10];
                    value3 = app.Sbare.Value;
                    t.Data3=value3;
                    
                    app.Snormal.ItemsData = [1 2 3 4 5 6 7 8 9 10];
                    value4 = app.Snormal.Value;
                    t.Data4=value4;


                    conn = database('mysql','U0533008','wwwwwwww');
                   
                     if(isempty(conn.message))
                         disp('database connected')
                       
                     else
                         disp('cannot connected')
                         disp(conn.message);
                         return
                     end
                    
                    c = {value1,value2,value3,value4};
                    colnames = {'size' 'shape' 'bare' 'normal'};
                    data = cell2table(c,'VariableNames',colnames);
                    tablename = 'breast_cancer';
                    sqlwrite(conn,tablename,data);
                    rows = sqlread(conn,tablename);
                    close(conn);
                    writeFIS(fis,'fuzzy2');
                    
                case '否'
                    return
            end
        end

        % Button pushed function: Button_size
        function Button_sizePushed(app, event)
            app.nKnob.ItemsData = [0.5 0.6 0.7 0.8 0.9 1];
            c1 = app.nKnob.Value;
            a1 = app.sizemean;
            b1 = app.sizestd;
            fis = mamfis('Name','fuzzy2');
            fis = addInput(fis,[0 10],'Name','size');
            addMF(fis,'size','trimf',[-99999 a1-c1*b1 a1],'Name','low');
            addMF(fis,'size','trimf',[a1-c1*b1 a1 a1+c1*b1],'Name','medium');
            addMF(fis,'size','trimf',[a1 a1+c1*b1 99999],'Name','high');
            %plotmf(fis,'input',1);
            
            
            x = (0:0.1:10)';           
            y1 = trimf(x, [-99999 a1-c1*b1 a1]);
            y2 = trimf(x, [a1-c1*b1 a1 a1+c1*b1]);
            y3 = trimf(x, [a1 a1+c1*b1 99999]);
            plot(app.UIAxes, x, [y1 y2 y3]);
            
            app.Label_low.Visible = 'on';
            app.Label_medium.Visible = 'on';
            app.Label_high.Visible = 'on';
        end

        % Button pushed function: Button_shape
        function Button_shapePushed(app, event)
            app.nKnob.ItemsData = [0.5 0.6 0.7 0.8 0.9 1];
            c1 = app.nKnob.Value;
            a2 = app.shapemean;
            b2 = app.shapestd;
            fis = mamfis('Name','fuzzy2');
            fis = addInput(fis,[0 10],'Name','shape');
            addMF(fis,'shape','trimf',[-99999 a2-c1*b2 a2],'Name','low');
            addMF(fis,'shape','trimf',[a2-c1*b2 a2 a2+c1*b2],'Name','medium');
            addMF(fis,'shape','trimf',[a2 a2+c1*b2 99999],'Name','high');
            %plotmf(fis,'input',1);
            
            
            x = (0:0.1:10)';       
            y1 = trimf(x, [-99999 a2-c1*b2 a2]);
            y2 = trimf(x, [a2-c1*b2 a2 a2+c1*b2]);
            y3 = trimf(x, [a2 a2+c1*b2 99999]);
            plot(app.UIAxes, x, [y1 y2 y3]);
            
            app.Label_low.Visible = 'on';
            app.Label_medium.Visible = 'on';
            app.Label_high.Visible = 'on';
        end

        % Button pushed function: Button_bare
        function Button_barePushed(app, event)
            app.nKnob.ItemsData = [0.5 0.6 0.7 0.8 0.9 1];
            c1 = app.nKnob.Value;
            a3 = app.baremean;
            b3 = app.barestd;
            fis = mamfis('Name','fuzzy2');
            fis = addInput(fis,[0 10],'Name','bare');
            addMF(fis,'bare','trimf',[-99999 a3-c1*b3 a3],'Name','low');
            addMF(fis,'bare','trimf',[a3-c1*b3 a3 a3+c1*b3],'Name','medium');
            addMF(fis,'bare','trimf',[a3 a3+c1*b3 99999],'Name','high');
            %plotmf(fis,'input',1);
            
            
            x = (0:0.1:10)';      
            y1 = trimf(x, [-99999 a3-c1*b3 a3]);
            y2 = trimf(x, [a3-c1*b3 a3 a3+c1*b3]);
            y3 = trimf(x, [a3 a3+c1*b3 99999]);
            plot(app.UIAxes, x, [y1 y2 y3]);
            
            app.Label_low.Visible = 'on';
            app.Label_medium.Visible = 'on';
            app.Label_high.Visible = 'on';
        end

        % Button pushed function: Button_normal
        function Button_normalPushed(app, event)
            app.nKnob.ItemsData = [0.5 0.6 0.7 0.8 0.9 1];
            c1 = app.nKnob.Value;
            a4 = app.normalmean;
            b4 = app.normalstd;
            fis = mamfis('Name','fuzzy2');
            fis = addInput(fis,[0 10],'Name','normal');
            addMF(fis,'normal','trimf',[-99999 a4-c1*b4 a4],'Name','low');
            addMF(fis,'normal','trimf',[a4-c1*b4 a4 a4+c1*b4],'Name','medium');
            addMF(fis,'normal','trimf',[a4 a4+c1*b4 99999],'Name','high');
            %plotmf(fis,'input',1);
            
            
            x = (0:0.1:10)';         
            y1 = trimf(x, [-99999 a4-c1*b4 a4]);
            y2 = trimf(x, [a4-c1*b4 a4 a4+c1*b4]);
            y3 = trimf(x, [a4 a4+c1*b4 99999]);
            plot(app.UIAxes, x, [y1 y2 y3]);
            
            app.Label_low.Visible = 'on';
            app.Label_medium.Visible = 'on';
            app.Label_high.Visible = 'on';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            conn = database('mysql','U0533008','wwwwwwww');
            
            if(isempty(conn.message))
                disp('database connected')
                
            else
                disp('cannot connected')
                disp(conn.message);
                return
            end
            
            sql = 'select size from breast_cancer';
            cursor = exec(conn, sql);      
            setdbprefs('DataReturnFormat', 'cellarray');	
            cursor = fetch(cursor);			
            sizeall = cursor.Data;
            app.aversize.Value = mean([sizeall{:}]);
            app.sdsize.Value = std([sizeall{:}]);
            app.sizemean = mean([sizeall{:}]);
            app.sizestd = std([sizeall{:}]);        
            close(cursor);
            
            sql2 = 'select shape from breast_cancer';
            cursor2 = exec(conn, sql2);
            setdbprefs('DataReturnFormat', 'cellarray');
            cursor2 = fetch(cursor2);
            shapeall = cursor2.Data;
            app.avershape.Value = mean([shapeall{:}]);
            app.sdshape.Value = std([shapeall{:}]);
            app.shapemean = mean([shapeall{:}]);
            app.shapestd = std([shapeall{:}]);
            close(cursor2);
            
            sql3 = 'select bare from breast_cancer';
            cursor3 = exec(conn, sql3);
            setdbprefs('DataReturnFormat', 'cellarray');
            cursor3 = fetch(cursor3);
            bareall = cursor3.Data;
            app.averbare.Value = mean([bareall{:}]);
            app.sdbare.Value = std([bareall{:}]);
            app.baremean = mean([bareall{:}]);
            app.barestd = std([bareall{:}]);
            close(cursor3);
            
            sql4 = 'select normal from breast_cancer';
            cursor4 = exec(conn, sql4);
            setdbprefs('DataReturnFormat', 'cellarray');
            cursor4 = fetch(cursor4);
            normalall = cursor4.Data;
            app.avernormal.Value = mean([normalall{:}]);
            app.sdnormal.Value = std([normalall{:}]);
            app.normalmean = mean([normalall{:}]);
            app.normalstd = std([normalall{:}]);
            close(cursor4);
            					
            close(conn);					
            %setdbprefs('DataReturnFormat', 'structure');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUIFigure = uifigure('Visible', 'off');
            app.MainUIFigure.Name = 'Main';
            app.MainUIFigure.CloseRequestFcn = createCallbackFcn(app, @MainUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.MainUIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '系統說明';
            app.Image3 = uiimage(app.Tab);
            app.Image3.ImageSource = 'bg.jpg';
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = 'benign.png';
            app.Image2 = uiimage(app.Tab);
            app.Image2.ImageSource = 'malignant.png';
            app.Label_17 = uilabel(app.Tab);
            app.Label_17.Text = {'系統說明：'; '初期乳癌檢測採用乳房攝影與乳房超音波，當檢查結果為異常時，將會更進一步做侵入性的'; '檢查，而透過醫生的判斷再加上此系統的預測，不但能減少更具侵入性的程序且可以減少誤'; '診樣本。下圖為良性與惡性的細胞示意圖。'; ''; '系統特色：'; '（1）避免醫療資源浪費'; '       運用特徵選取方法篩選出重要且相關的屬性。透過醫生的判斷再加上此系統的預測，'; '     不但能減少更具侵入性的程序且可以減少誤診樣本。'; '（2）解決醫療屬性判定過程的模糊性'; '       將篩選出的重要屬性結合模糊推論，建立預測模式，以提高正確判斷病症的可能性。'; '（3）即時更新資料庫'; '       本系統將輸入的每筆資料即時匯入資料庫中，更新各屬性的平均數與標準差，讓預測'; '     結果更符合實際的狀況，增加系統的彈性。'; ''};
            app.Label_18 = uilabel(app.Tab);
            app.Label_18.Text = '良性細胞';
            app.Label_19 = uilabel(app.Tab);
            app.Label_19.Text = '惡性細胞';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '流程說明';
            app.Image4 = uiimage(app.Tab_2);
            app.Image4.ImageSource = 'bg.jpg';
            app.Panel_3 = uipanel(app.Tab_2);
            app.Panel_3.Title = '各屬性平均數及標準差';
            app.aversize = uieditfield(app.Panel_3, 'numeric');
            app.aversize.Value = 3.151;
            app.avershape = uieditfield(app.Panel_3, 'numeric');
            app.avershape.Value = 3.215;
            app.averbare = uieditfield(app.Panel_3, 'numeric');
            app.averbare.Value = 3.545;
            app.avernormal = uieditfield(app.Panel_3, 'numeric');
            app.avernormal.Value = 2.87;
            app.sdsize = uieditfield(app.Panel_3, 'numeric');
            app.sdsize.Value = 3.065;
            app.sdshape = uieditfield(app.Panel_3, 'numeric');
            app.sdshape.Value = 2.989;
            app.sdbare = uieditfield(app.Panel_3, 'numeric');
            app.sdbare.Value = 3.644;
            app.sdnormal = uieditfield(app.Panel_3, 'numeric');
            app.sdnormal.Value = 3.053;
            app.Label_20 = uilabel(app.Panel_3);
            app.Label_20.Text = '細胞大小一致性';
            app.Label_21 = uilabel(app.Panel_3);
            app.Label_21.Text = '細胞形狀一致性';
            app.Label_22 = uilabel(app.Panel_3);
            app.Label_22.Text = '細胞核裸露程度';
            app.Label_23 = uilabel(app.Panel_3);
            app.Label_23.Text = '細胞核正常程度';
            app.Label_mean = uilabel(app.Panel_3);
            app.Label_mean.Text = '平均數';
            app.Label_standard_deviation = uilabel(app.Panel_3);
            app.Label_standard_deviation.Text = '標準差';
            app.step = uilabel(app.Tab_2);
            app.step.Text = {'此系統採用「細胞大小一致性」、「細胞形狀一致性」、「細胞核裸露程度」與「細胞核正常'; '程度」四個重要的特徵屬性進行預測。'; ''; '步驟一：利用集群分析法平均數加減ｎ倍標準差定義資料的中心點，可選擇0.5、0.6、0.7、'; '        0.8、0.9倍。'; ''; '步驟二：檢視各屬性所選擇倍數之低、中、高的危險程度。'; ''; '步驟三：選擇各屬性的程度。'; ''; '步驟四：解模糊化主要是將模糊推論後所產生的模糊化結果，將它轉化為明確數值或是對等的'; '        明確值輸出。本系統使用Mamdani的min-max模糊推論方式，可選擇重心法(centroid)'; '        、面積平分法(bisector)、最大隸屬度法(平均值mom、最小值som、最大值lom)。'; ''; '       '};
            app.Button = uibutton(app.Tab_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '檢視';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '資料輸入及預測結果';
            app.Image5 = uiimage(app.Tab_3);
            app.Image5.ImageSource = 'bg.jpg';
            app.Panel_4 = uipanel(app.Tab_3);
            app.Panel_4.Title = '步驟二：資料分群圖';
            app.UIAxes = uiaxes(app.Panel_4);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, '隸屬度')
            app.UIAxes.XTick = [0 2 4 6 8 10];
            app.UIAxes.XTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'};
            app.Button_normal = uibutton(app.Panel_4, 'push');
            app.Button_normal.ButtonPushedFcn = createCallbackFcn(app, @Button_normalPushed, true);
            app.Button_normal.Text = '細胞核正常程度';
            app.Button_shape = uibutton(app.Panel_4, 'push');
            app.Button_shape.ButtonPushedFcn = createCallbackFcn(app, @Button_shapePushed, true);
            app.Button_shape.Text = '細胞形狀一致性';
            app.Button_bare = uibutton(app.Panel_4, 'push');
            app.Button_bare.ButtonPushedFcn = createCallbackFcn(app, @Button_barePushed, true);
            app.Button_bare.Text = '細胞核裸露程度';
            app.Button_size = uibutton(app.Panel_4, 'push');
            app.Button_size.ButtonPushedFcn = createCallbackFcn(app, @Button_sizePushed, true);
            app.Button_size.Text = '細胞大小一致性';
            app.Label_low = uilabel(app.Panel_4);
            app.Label_low.Visible = 'off';
            app.Label_low.Text = '一 低';
            app.Label_medium = uilabel(app.Panel_4);
            app.Label_medium.Visible = 'off';
            app.Label_medium.Text = '一 中';
            app.Label_high = uilabel(app.Panel_4);
            app.Label_high.Visible = 'off';
            app.Label_high.Text = '一 高';
            app.nPanel = uipanel(app.Tab_3);
            app.nPanel.Title = '步驟一：資料分群 (平均數加減 n 倍標準差)';
            app.nKnobLabel = uilabel(app.nPanel);
            app.nKnobLabel.Text = 'n';
            app.nKnob = uiknob(app.nPanel, 'discrete');
            app.nKnob.Items = {'0.5', '0.6', '0.7', '0.8', '0.9'};
            app.nKnob.Value = '0.5';
            app.Button_predict = uibutton(app.Tab_3, 'push');
            app.Button_predict.ButtonPushedFcn = createCallbackFcn(app, @Button_predictPushed2, true);
            app.Button_predict.Text = '預測結果';
            app.Panel_5 = uipanel(app.Tab_3);
            app.Panel_5.Title = '步驟三：輸入資料';
            app.Label_24 = uilabel(app.Panel_5);
            app.Label_24.Text = '細胞大小一致性';
            app.Ssize = uidropdown(app.Panel_5);
            app.Ssize.Items = {'1：細胞大小完全一致', '2：細胞大小90%一致', '3：細胞大小80%一致', '4：細胞大小65%一致', '5：細胞大小大於50%一致', '6：細胞大小小於50%一致', '7：細胞大小35%一致', '8：細胞大小20%一致', '9：細胞大小10%一致', '10：細胞大小幾乎不一致'};
            app.Ssize.Value = '1：細胞大小完全一致';
            app.Label_25 = uilabel(app.Panel_5);
            app.Label_25.Text = '細胞形狀一致性';
            app.Sshape = uidropdown(app.Panel_5);
            app.Sshape.Items = {'1：細胞形狀完全一致', '2：細胞形狀90%一致', '3：細胞形狀80%一致', '4：細胞形狀65%一致', '5：細胞形狀大於50%一致', '6：細胞形狀小於50%一致', '7：細胞形狀35%一致', '8：細胞形狀20%一致', '9：細胞形狀10%一致', '10：細胞形狀幾乎不一致'};
            app.Sshape.Value = '1：細胞形狀完全一致';
            app.Label_26 = uilabel(app.Panel_5);
            app.Label_26.Text = '細胞核裸露程度';
            app.Sbare = uidropdown(app.Panel_5);
            app.Sbare.Items = {'1：完全沒有細胞質的細胞核', '2：20%的細胞核有細胞質', '3：30%的細胞核有細胞質', '4：40%的細胞核有細胞質', '5：50%的細胞核有細胞質', '6：60%的細胞核有細胞質', '7：70%的細胞核有細胞質', '8：80%的細胞核有細胞質', '9：90%的細胞核有細胞質', '10：所有細胞核都有細胞質'};
            app.Sbare.Value = '1：完全沒有細胞質的細胞核';
            app.Label_27 = uilabel(app.Panel_5);
            app.Label_27.Text = '細胞核正常程度';
            app.Snormal = uidropdown(app.Panel_5);
            app.Snormal.Items = {'1：所有核仁完全正常(samll, one per cell, barely visible)', '2：20%的核仁不正常', '3：30%的核仁不正常', '4：40%的核仁不正常', '5：50%的核仁不正常', '6：60%的核仁不正常', '7：70%的核仁不正常', '8：80%的核仁不正常', '9：90%的核仁不正常', '10：所有核仁不正常'};
            app.Snormal.Value = '1：所有核仁完全正常(samll, one per cell, barely visible)';
            % Show the figure after all components are created
            app.MainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end