classdef test2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Image            matlab.ui.control.Image
        UIAxes           matlab.ui.control.UIAxes
        EditField_3      matlab.ui.control.NumericEditField
        EditField_4      matlab.ui.control.EditField
        Button           matlab.ui.control.Button
        Lamp             matlab.ui.control.Lamp
        ButtonGroup      matlab.ui.container.ButtonGroup
        Button_centroid  matlab.ui.control.RadioButton
        Button_bisector  matlab.ui.control.RadioButton
        Button_lom       matlab.ui.control.RadioButton
        Button_mom       matlab.ui.control.RadioButton
        Button_som       matlab.ui.control.RadioButton
        Label            matlab.ui.control.Label
        Label_2          matlab.ui.control.Label
    end

    
    properties (Access = public)
        Data1
        Data2
        Data3
        Data4
        % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            fis=readfis('fuzzy2');
            selectedButton = app.ButtonGroup.SelectedObject;
            
            switch app.ButtonGroup.SelectedObject
                case app.Button_centroid
                    fis.defuzzmethod = 'centroid';
                    
                case app.Button_bisector
                    fis.defuzzmethod = 'bisector';
                    
                case app.Button_mom
                    fis.defuzzmethod = 'mom';
                    
                case app.Button_som
                    fis.defuzzmethod = 'som';
                    
                case app.Button_lom
                    fis.defuzzmethod = 'lom';
                    
            end
            writeFIS(fis,'fuzzy2');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            value1=app.Data1;
            value2=app.Data2;
            value3=app.Data3;
            value4=app.Data4;
            
            a=readfis('fuzzy2');
            out=evalfis([value1 value2 value3 value4],a);
            app.EditField_3.Value = out;
            
            if out<=0.5
                %set(app.block_meanings,'string','定期追蹤');
                app.EditField_4.Value = '定期追蹤';
                app.Lamp.Color = 'green';
            else
                %set(app.block_meanings,'string','危險族群');
                app.EditField_4.Value = '危險族群';
                app.Lamp.Color = 'red';
            end
            
            [output,fuzzifiedIn,ruleOut,aggregatedOut,ruleFiring] = evalfis(a,[value1 value2 value3 value4]);
            outputRange = linspace(a.output.range(1),a.output.range(2),length(aggregatedOut))';
            plot(app.UIAxes,outputRange,aggregatedOut,[output output],[0 1]);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'bg.jpg';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '罹患疾病的可能性')
            ylabel(app.UIAxes, '隸屬度')
            app.UIAxes.XTick = [0 0.25 0.5 0.75 1];
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '顯示預測結果';
            app.Lamp = uilamp(app.UIFigure);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '步驟四：解模糊化';
            app.Button_centroid = uiradiobutton(app.ButtonGroup);
            app.Button_centroid.Text = '重心法';
            app.Button_centroid.Value = true;
            app.Button_bisector = uiradiobutton(app.ButtonGroup);
            app.Button_bisector.Text = '面積平分法';
            app.Button_lom = uiradiobutton(app.ButtonGroup);
            app.Button_lom.Text = '最大隸屬度取最大值';
            app.Button_mom = uiradiobutton(app.ButtonGroup);
            app.Button_mom.Text = '最大隸屬度取平均值';
            app.Button_som = uiradiobutton(app.ButtonGroup);
            app.Button_som.Text = '最大隸屬度取最小值';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '解模糊化數值';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '結果說明';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end